classdef CR5Teach < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        JointControlLabel          matlab.ui.control.Label
        ResetButton                matlab.ui.control.Button
        DestinationLabel           matlab.ui.control.Label
        Q1SliderLabel              matlab.ui.control.Label
        Q1Slider                   matlab.ui.control.Slider
        Q2SliderLabel              matlab.ui.control.Label
        Q2Slider                   matlab.ui.control.Slider
        Q3SliderLabel              matlab.ui.control.Label
        Q3Slider                   matlab.ui.control.Slider
        Q4SliderLabel              matlab.ui.control.Label
        Q4Slider                   matlab.ui.control.Slider
        Q5SliderLabel              matlab.ui.control.Label
        Q5Slider                   matlab.ui.control.Slider
        Q6SliderLabel              matlab.ui.control.Label
        Q6Slider                   matlab.ui.control.Slider
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        ZEditField                 matlab.ui.control.NumericEditField
        RollEditFieldLabel         matlab.ui.control.Label
        RollEditField              matlab.ui.control.NumericEditField
        PitchEditFieldLabel        matlab.ui.control.Label
        PitchEditField             matlab.ui.control.NumericEditField
        YawEditFieldLabel          matlab.ui.control.Label
        YawEditField               matlab.ui.control.NumericEditField
        RMRCButton                 matlab.ui.control.Button
        ControlModeSwitchLabel     matlab.ui.control.Label
        ControlModeSwitch          matlab.ui.control.Switch
        EmmergencyStopSwitchLabel  matlab.ui.control.Label
        EmmergencyStopSwitch       matlab.ui.control.Switch
    end

    
    properties (Access = private)
        Property; % Description\
        robot; % Robot
        q = deg2rad([0    0   -100     0    90     0]); % Joint states
        handle;
        Tr; % Description
        ES = 0; % Emmergency Stop
        ovenPose = transl(0.7,0,0)*trotz(pi/2);
        pattyPose1 = transl(0.55,-0.55,0.5);
        pattyPose2 = transl(0.55,-0.2,0.5);
        pattyPose3 = transl(0.7,-0.2,0.5);
        pattyPose4 = transl(0.7,-0.55,0.5);
        fryPose = transl(0.35,0.25,0.67)*trotz(-pi/2);
        spatPose = transl(0.35,-0.08,0.52)*trotz(-pi/2);
        oven_h;
        patty_h1;
        patty_h2;
        patty_h3;
        patty_h4;
        fry_h;
        spat_h
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.robot = CR5;
            app.Tr = app.robot.model.fkine(app.q);
            app.XEditField.Value = app.Tr(1,4);
            app.YEditField.Value = app.Tr(2,4);
            app.ZEditField.Value = app.Tr(3,4);
            RPY = rad2deg(tr2rpy(app.Tr));
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
            hold on;
            % Loading objects
            % Load Oven
            [fOven,vOven,dataOven] = plyread('oven.ply','tri');
            ovenVertexColours = [dataOven.vertex.red,dataOven.vertex.green,dataOven.vertex.blue] / 255;
            app.oven_h = trisurf(fOven,vOven(:,1),vOven(:,2),vOven(:,3),'FaceVertexCData',ovenVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            updatedOvenPosition = [app.ovenPose*[vOven,ones(size(vOven,1),1)]']';
            app.oven_h.Vertices = updatedOvenPosition(:,1:3);
            
            % Load Patties
            [fPatty,vPatty,dataPatty] = plyread('patty.ply','tri');
            pattyVertexColours = [dataPatty.vertex.red,dataPatty.vertex.green,dataPatty.vertex.blue] / 255;
            app.patty_h1 = trisurf(fPatty,vPatty(:,1),vPatty(:,2),vPatty(:,3),'FaceVertexCData',pattyVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            app.patty_h2 = trisurf(fPatty,vPatty(:,1),vPatty(:,2),vPatty(:,3),'FaceVertexCData',pattyVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            app.patty_h3 = trisurf(fPatty,vPatty(:,1),vPatty(:,2),vPatty(:,3),'FaceVertexCData',pattyVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            app.patty_h4 = trisurf(fPatty,vPatty(:,1),vPatty(:,2),vPatty(:,3),'FaceVertexCData',pattyVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            updatedPattyPosition1 = [app.pattyPose1*[vPatty,ones(size(vPatty,1),1)]']';
            updatedPattyPosition2 = [app.pattyPose2*[vPatty,ones(size(vPatty,1),1)]']';
            updatedPattyPosition3 = [app.pattyPose3*[vPatty,ones(size(vPatty,1),1)]']';
            updatedPattyPosition4 = [app.pattyPose4*[vPatty,ones(size(vPatty,1),1)]']';
            app.patty_h1.Vertices = updatedPattyPosition1(:,1:3);
            app.patty_h2.Vertices = updatedPattyPosition2(:,1:3);
            app.patty_h3.Vertices = updatedPattyPosition3(:,1:3);
            app.patty_h4.Vertices = updatedPattyPosition4(:,1:3);
            
            % Load Fry basket
            [fFry,vFry,dataFry] = plyread('basket.ply','tri');
            fryVertexColours = [dataFry.vertex.red,dataFry.vertex.green,dataFry.vertex.blue] / 255;
            app.fry_h = trisurf(fFry,vFry(:,1),vFry(:,2),vFry(:,3),'FaceVertexCData',fryVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            updatedFryPosition = [app.fryPose*[vFry,ones(size(vFry,1),1)]']';
            app.fry_h.Vertices = updatedFryPosition(:,1:3);
            
            % Load Spatula
            app.spatPose = app.robot.model.fkine(app.q)*trotz(pi)*troty(pi);
            [fSpat,vSpat,dataSpat] = plyread('spatula.ply','tri');
            spatVertexColours = [dataSpat.vertex.red,dataSpat.vertex.green,dataSpat.vertex.blue] / 255;
            app.spat_h = trisurf(fSpat,vSpat(:,1),vSpat(:,2),vSpat(:,3),'FaceVertexCData',spatVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            updatedSpatPosition = [app.spatPose*[vSpat,ones(size(vSpat,1),1)]']';
            app.spat_h.Vertices = updatedSpatPosition(:,1:3);
            drawnow();
        end

        % Value changing function: Q1Slider
        function Q1SliderValueChanging(app, event)
            app.q(1) = deg2rad(event.Value);
            app.robot.model.plot(app.q);
            app.Tr = app.robot.model.fkine(app.q);
            RPY = rad2deg(tr2rpy(app.Tr));
            app.XEditField.Value = app.Tr(1,4);
            app.YEditField.Value = app.Tr(2,4);
            app.ZEditField.Value = app.Tr(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
        end

        % Value changing function: Q2Slider
        function Q2SliderValueChanging(app, event)
            app.q(2) = deg2rad(event.Value);
            app.robot.model.plot(app.q);
            app.Tr = app.robot.model.fkine(app.q);
            RPY = rad2deg(tr2rpy(app.Tr));
            app.XEditField.Value = app.Tr(1,4);
            app.YEditField.Value = app.Tr(2,4);
            app.ZEditField.Value = app.Tr(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
        end

        % Value changing function: Q3Slider
        function Q3SliderValueChanging(app, event)
            app.q(3) = deg2rad(event.Value);
            app.robot.model.plot(app.q);
            app.Tr = app.robot.model.fkine(app.q);
            RPY = rad2deg(tr2rpy(app.Tr));
            app.XEditField.Value = app.Tr(1,4);
            app.YEditField.Value = app.Tr(2,4);
            app.ZEditField.Value = app.Tr(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
        end

        % Value changing function: Q4Slider
        function Q4SliderValueChanging(app, event)
            app.q(4) = deg2rad(event.Value);
            app.robot.model.plot(app.q);
            app.Tr = app.robot.model.fkine(app.q);
            RPY = rad2deg(tr2rpy(app.Tr));
            app.XEditField.Value = app.Tr(1,4);
            app.YEditField.Value = app.Tr(2,4);
            app.ZEditField.Value = app.Tr(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
        end

        % Value changing function: Q5Slider
        function Q5SliderValueChanging(app, event)
            app.q(5) = deg2rad(event.Value);
            app.robot.model.plot(app.q);
            app.Tr = app.robot.model.fkine(app.q);
            RPY = rad2deg(tr2rpy(app.Tr));
            app.XEditField.Value = app.Tr(1,4);
            app.YEditField.Value = app.Tr(2,4);
            app.ZEditField.Value = app.Tr(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
        end

        % Value changing function: Q6Slider
        function Q6SliderValueChanging(app, event)
            app.q(6) = deg2rad(event.Value);
            app.robot.model.plot(app.q);
            app.Tr = app.robot.model.fkine(app.q);
            RPY = rad2deg(tr2rpy(app.Tr));
            app.XEditField.Value = app.Tr(1,4);
            app.YEditField.Value = app.Tr(2,4);
            app.ZEditField.Value = app.Tr(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.q = zeros(1,6);
            app.robot.model.plot(app.q);
            app.Tr = app.robot.model.fkine(app.q);
            RPY = rad2deg(tr2rpy(app.Tr));
            app.XEditField.Value = app.Tr(1,4);
            app.YEditField.Value = app.Tr(2,4);
            app.ZEditField.Value = app.Tr(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
        end

        % Button pushed function: RMRCButton
        function RMRCButtonPushed(app, event)
            T2 = rpy2tr(deg2rad(app.RollEditField.Value),deg2rad(app.PitchEditField.Value),deg2rad(app.YawEditField.Value))*transl(app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value);
            [qMatrix] = RMRC(app.robot,app.Tr,T2,app.q);
            app.q = qMatrix(end,:);
            app.Tr = T2;
            app.Q1Slider.Value = app.q(1);
            app.Q2Slider.Value = app.q(2);
            app.Q3Slider.Value = app.q(3);
            app.Q4Slider.Value = app.q(4);
            app.Q5Slider.Value = app.q(5);
            app.Q6Slider.Value = app.q(6);
            for i = 1 : size(qMatrix,1)
                while app.EmmergencyStopSwitch.Value == "Stop"
                    pause(0.01)
                    if app.EmmergencyStopSwitch.Value == "Run"
                        break;
                    end
                end
                app.robot.model.plot(qMatrix(i,:));
            end
        end

        % Value changed function: ControlModeSwitch
        function ControlModeSwitchValueChanged(app, event)
            %% Reference UTSOnline, Week11 Material
            id = 1;
            joy = vrjoystick(id);
            caps(joy)
            app.robot.model.plot(app.q);          % Plot robot in initial configuration
            app.robot.model.delay = 0.001;
            dt = 0.15;      % Set time step for simulation (seconds
            lambda = 0.005;
            Ka = diag([0.3 0.3 0.3 0.5 0.5 0.5]); % admittance gain matrix
            while app.ControlModeSwitch.Value == "Joystick"
                % read joystick
                [axes, buttons, ~] = read(joy);
                while app.EmmergencyStopSwitch.Value == "Stop"
                    pause(0.01)
                    if app.EmmergencyStopSwitch.Value == "Run"
                        break;
                    end
                end
                
                % -------------------------------------------------------------
                % YOUR CODE GOES HERE
                % 1 - turn joystick input into an end-effector velocity command
                Kv = 0.2; % linear velocity gain
                Kw = 1.0; % angular velocity gain
                
                vx = Kv*axes(1);
                vy = Kv*axes(2);
                vz = Kv*(buttons(1)-buttons(4));
                
                wx = Kw*axes(4);
                wy = Kw*axes(5);
                wz = Kw*axes(3);
                
                dx = [vx;vy;vz;wx;wy;wz]; % combined velocity vector
                
                dx = Ka*dx; % convert wrench into end-effector velocity command
                % 2 - use J inverse to calculate joint velocity
                J = app.robot.model.jacob0(app.q);
                Jinv_dls = inv((J'*J)+lambda^2*eye(6))*J';
                dq = Jinv_dls*dx;
                
                % 3 - apply joint velocity to step robot joint angles
                app.q = app.q + dq'*dt;
                
                % -------------------------------------------------------------
                
                % Update plot
                app.robot.model.animate(app.q);
            end
        end

        % Callback function
        function EmmergencyStopButtonPushed(app, event)
            
            if app.ES == 1
                app.ES = 0
            else app.ES = 1
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.JointControlLabel = uilabel(app.UIFigure);
            app.JointControlLabel.Text = 'Joint Control';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DestinationLabel = uilabel(app.UIFigure);
            app.DestinationLabel.Text = 'Destination';
            app.Q1SliderLabel = uilabel(app.UIFigure);
            app.Q1SliderLabel.Text = 'Q1';
            app.Q1Slider = uislider(app.UIFigure);
            app.Q1Slider.Limits = [-180 180];
            app.Q1Slider.MajorTicks = [-180 -90 0 90 180];
            app.Q1Slider.MajorTickLabels = {'-180', '-90', '0', '90', '180'};
            app.Q1Slider.ValueChangingFcn = createCallbackFcn(app, @Q1SliderValueChanging, true);
            app.Q1Slider.MinorTicks = [-180 -168 -156 -144 -132 -120 -108 -96 -84 -72 -60 -48 -36 -24 -12 0 12 24 36 48 60 72 84 96 108 120 132 144 156 168 180];
            app.Q2SliderLabel = uilabel(app.UIFigure);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q2Slider = uislider(app.UIFigure);
            app.Q2Slider.Limits = [-90 90];
            app.Q2Slider.MajorTicks = [-90 -45 0 45 90];
            app.Q2Slider.ValueChangingFcn = createCallbackFcn(app, @Q2SliderValueChanging, true);
            app.Q3SliderLabel = uilabel(app.UIFigure);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q3Slider = uislider(app.UIFigure);
            app.Q3Slider.Limits = [-160 160];
            app.Q3Slider.MajorTicks = [-160 -80 0 80 160];
            app.Q3Slider.ValueChangingFcn = createCallbackFcn(app, @Q3SliderValueChanging, true);
            app.Q4SliderLabel = uilabel(app.UIFigure);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q4Slider = uislider(app.UIFigure);
            app.Q4Slider.Limits = [-180 180];
            app.Q4Slider.MajorTicks = [-180 -90 0 90 180];
            app.Q4Slider.ValueChangingFcn = createCallbackFcn(app, @Q4SliderValueChanging, true);
            app.Q5SliderLabel = uilabel(app.UIFigure);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q5Slider = uislider(app.UIFigure);
            app.Q5Slider.Limits = [-180 180];
            app.Q5Slider.MajorTicks = [-180 -90 0 90 180];
            app.Q5Slider.ValueChangingFcn = createCallbackFcn(app, @Q5SliderValueChanging, true);
            app.Q6SliderLabel = uilabel(app.UIFigure);
            app.Q6SliderLabel.Text = 'Q6';
            app.Q6Slider = uislider(app.UIFigure);
            app.Q6Slider.Limits = [-360 360];
            app.Q6Slider.MajorTicks = [-360 -180 0 180 360];
            app.Q6Slider.ValueChangingFcn = createCallbackFcn(app, @Q6SliderValueChanging, true);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.RMRCButton = uibutton(app.UIFigure, 'push');
            app.RMRCButton.ButtonPushedFcn = createCallbackFcn(app, @RMRCButtonPushed, true);
            app.RMRCButton.Text = 'RMRC';
            app.ControlModeSwitchLabel = uilabel(app.UIFigure);
            app.ControlModeSwitchLabel.Text = 'Control Mode';
            app.ControlModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ControlModeSwitch.Items = {'Normal', 'Joystick'};
            app.ControlModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlModeSwitchValueChanged, true);
            app.ControlModeSwitch.Value = 'Normal';
            app.EmmergencyStopSwitchLabel = uilabel(app.UIFigure);
            app.EmmergencyStopSwitchLabel.Text = 'Emmergency Stop';
            app.EmmergencyStopSwitch = uiswitch(app.UIFigure, 'slider');
            app.EmmergencyStopSwitch.Items = {'Run', 'Stop'};
            app.EmmergencyStopSwitch.Value = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CR5Teach

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end