classdef CR5TeachFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        JointControlLabel               matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        DestinationLabel                matlab.ui.control.Label
        Q1SliderLabel                   matlab.ui.control.Label
        Q1Slider                        matlab.ui.control.Slider
        Q2SliderLabel                   matlab.ui.control.Label
        Q2Slider                        matlab.ui.control.Slider
        Q3SliderLabel                   matlab.ui.control.Label
        Q3Slider                        matlab.ui.control.Slider
        Q4SliderLabel                   matlab.ui.control.Label
        Q4Slider                        matlab.ui.control.Slider
        Q5SliderLabel                   matlab.ui.control.Label
        Q5Slider                        matlab.ui.control.Slider
        Q6SliderLabel                   matlab.ui.control.Label
        Q6Slider                        matlab.ui.control.Slider
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        RollEditFieldLabel              matlab.ui.control.Label
        RollEditField                   matlab.ui.control.NumericEditField
        PitchEditFieldLabel             matlab.ui.control.Label
        PitchEditField                  matlab.ui.control.NumericEditField
        YawEditFieldLabel               matlab.ui.control.Label
        YawEditField                    matlab.ui.control.NumericEditField
        RMRCButton                      matlab.ui.control.Button
        ControlModeLabel                matlab.ui.control.Label
        ControlModeSwitch               matlab.ui.control.Switch
        EmergencyStopSwitchLabel        matlab.ui.control.Label
        EmergencyStopSwitch             matlab.ui.control.Switch
        FlipthePattiesButton            matlab.ui.control.Button
        WorkerPositionSliderLabel       matlab.ui.control.Label
        WorkerPositionSlider            matlab.ui.control.Slider
        MoveWarningSymbolForwardButton  matlab.ui.control.Button
        MoveWarningSymbolBackwardButton  matlab.ui.control.Button
        RotateWarningSymbolClockwiseButton  matlab.ui.control.Button
        RotateWarningSymbolCounterClockwiseButton  matlab.ui.control.Button
        VisualServoingSwitchLabel       matlab.ui.control.Label
        VisualServoingSwitch            matlab.ui.control.Switch
    end

    
    properties (Access = private)
        clf;
        Property; % Description\
        robot; % Robot
        q = deg2rad([0    0   -100     0    90     0]); % Joint states
        qn = deg2rad([0    0   -100     0    90     0]); % Joint states
        handle;
        Tr; % Description
        ES = 0; % Emmergency Stop
        ovenPose = transl(0.7,0,0)*trotz(pi/2);
        pattyPose1 = transl(0.55,-0.55,0.5)*trotz(-pi/2);
        pattyPose2 = transl(0.55,-0.2,0.5)*trotz(-pi/2);
        pattyPose3 = transl(0.7,-0.2,0.5)*trotz(-pi/2);
        pattyPose4 = transl(0.7,-0.55,0.5)*trotz(-pi/2);
        fryPose = transl(0.35,0.25,0.67)*trotz(-pi/2);
        spatPose = transl(0.35,-0.08,0.52)*trotz(-pi/2);
        EE2Patty = transl(0,0.08,0);
        lightPose=transl(1.4,0,0);
        lightPose1=transl(0,1.4,0)*trotz(pi/2);
        concretePose1;
        floorPose=transl(0,0,0);
        tonyPose = transl(0,2,0);
        centerpnt = [0.5,1.5,0.5];
        side = 0.5;
        fTony;
        vTony;
        dataTony;
        fNormalTony;
        tony_h;
        tonyVertexCount;
        oven_h;
        patty_h1;
        patty_h2;
        patty_h3;
        patty_h4;
        fry_h;
        spat_h;
        concrete1_h;
        floor_h;
        light_h;
        light1_h;
        vFry;
        vOven;
        vPatty;
        vSpat;
        vFloor;
        vLight;
        vCube;
        cam = CentralCamera('focal', 0.08, 'pixel', 10e-5, ...
            'resolution', [1024 1024], 'centre', [512 512],'name', 'CR5cam');
        qCam = deg2rad([90   -30   -60     90    90     0])';
        % deg2rad([0    -60   -30     90    90     0])'
        pStar = [937 116 116 937; 184 184 1005 1005];
        P=[0.4,-0.1,-0.1,0.4;;1.15,1.15,1.15,1.15;;1.25,1.25,0.75,0.75];
        facesP = [1 2 3 4];
        P_P;
        fps = 100;
        lambda = 0.9;
        depth = mean ([0.4,-0.1,-0.1,0.4]);
        
    end
    
    methods (Access = private)
        
        
        function [] = e_Stop(app)
            while app.EmergencyStopSwitch.Value == "Stop"
                pause(0.01)
                if app.EmergencyStopSwitch.Value == "Run"
                    break;
                end
            end
        end
        
        function [qMeat] = mTraj(app,trans1,trans2)
            mStep = 50;
            s = lspb(0,1,mStep);
            qMeat = nan(mStep,4,4);
            for i= 1 : mStep
                qMeat(i,:,:) = (1-s(i)).*trans1 + s(i).*trans2;
            end
        end
        %% Is Collision (REF: LAB 5 SOLUTION ON UTSONLINE)
        % This is based upon the output of questions 2.5 and 2.6
        % Given a robot model (robot), and trajectory (i.e. joint state vector) (qMatrix)
        % and triangle obstacles in the environment (faces,vertex,faceNormals)
        function result = IsCollision(app,robot,qMatrix,faces,vertex,faceNormals,returnOnceFound)
            if nargin < 7
                returnOnceFound = true;
            end
            result = false;
            
            for qIndex = 1:size(qMatrix,1)
                % Get the transform of every joint (i.e. start and end of every link)
                tr = GetLinkPoses(app,qMatrix(qIndex,:), robot);
                
                % Go through each link and also each triangle face
                for i = 1 : size(tr,3)-1
                    for faceIndex = 1:size(faces,1)
                        vertOnPlane = vertex(faces(faceIndex,1)',:);
                        [intersectP,check] = LinePlaneIntersection(faceNormals(faceIndex,:),vertOnPlane,tr(1:3,4,i)',tr(1:3,4,i+1)');
                        if check == 1 && IsIntersectionPointInsideTriangle(app,intersectP,vertex(faces(faceIndex,:)',:))
                            %plot3(intersectP(1),intersectP(2),intersectP(3),'g*');
                            disp('Intersection');
                            result = true;
                            if returnOnceFound
                                return
                            end
                        end
                    end
                end
            end
        end
        %% GET LINK POSES (REF: LAB 5 SOLUTION ON UTSONLINE)
        % q - robot joint angles
        % robot -  seriallink robot model
        % transforms - list of transforms
        function [ transforms ] = GetLinkPoses(app,q, robot)
            
            links = robot.links;
            transforms = zeros(4, 4, length(links) + 1);
            transforms(:,:,1) = robot.base;
            
            for i = 1:length(links)
                L = links(1,i);
                
                current_transform = transforms(:,:, i);
                
                current_transform = current_transform * trotz(q(1,i) + L.offset) * ...
                    transl(0,0, L.d) * transl(L.a,0,0) * trotx(L.alpha);
                transforms(:,:,i + 1) = current_transform;
            end
        end
        %% Is Collision (REF: LAB 5 SOLUTION ON UTSONLINE)
        % This is based upon the output of questions 2.5 and 2.6
        % Given a robot model (robot), and trajectory (i.e. joint state vector) (qMatrix)
        % and triangle obstacles in the environment (faces,vertex,faceNormals)
        function result = IsCollisionForObjects(app,T,faces,vertex,faceNormals,returnOnceFound)
            if nargin < 5
                returnOnceFound = true;
            end
            result = false;
            
            % Get the transform of every joint (i.e. start and end of every link)
            
            % Go through each link and also each triangle face
            for i = 1: 1 : 7
                for faceIndex = 1:size(faces,1)
                    vertOnPlane = vertex(faces(faceIndex,1)',:);
                    if isequal(T(1:3,1:3),rotz(pi/2))
                        [intersectP,check] = LinePlaneIntersection(faceNormals(faceIndex,:),vertOnPlane,[(T(1,end)+1.4) T(2,end) i*0.2],[(T(1,end)-1.4) T(2,end) i*0.2]);
                    else
                        [intersectP,check] = LinePlaneIntersection(faceNormals(faceIndex,:),vertOnPlane,[T(1,end) (T(2,end)+1.4) i*0.2],[T(1,end) (T(2,end)-1.4) i*0.2]);
                    end
                    
                    if check == 1 && IsIntersectionPointInsideTriangle(app,intersectP,vertex(faces(faceIndex,:)',:))
                        %plot3(intersectP(1),intersectP(2),intersectP(3),'g*');
                        %disp('Intersection');
                        result = true;
                        if returnOnceFound
                            return;
                        end
                    end
                end
            end
        end
        
        %% IsIntersectionPointInsideTriangle (REF: LAB 5 SOLUTION ON UTSONLINE)
        % Given a point which is known to be on the same plane as the triangle
        % determine if the point is
        % inside (result == 1) or
        % outside a triangle (result ==0 )
        function result = IsIntersectionPointInsideTriangle(app,intersectP,triangleVerts)
            
            u = triangleVerts(2,:) - triangleVerts(1,:);
            v = triangleVerts(3,:) - triangleVerts(1,:);
            
            uu = dot(u,u);
            uv = dot(u,v);
            vv = dot(v,v);
            
            w = intersectP - triangleVerts(1,:);
            wu = dot(w,u);
            wv = dot(w,v);
            
            D = uv * uv - uu * vv;
            
            % Get and test parametric coords (s and t)
            s = (uv * wv - vv * wu) / D;
            if (s < 0.0 || s > 1.0)        % intersectP is outside Triangle
                result = 0;
                return;
            end
            
            t = (uv * wu - uu * wv) / D;
            if (t < 0.0 || (s + t) > 1.0)  % intersectP is outside Triangle
                result = 0;
                return;
            end
            
            result = 1;                      % intersectP is in Triangle
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clf
            app.robot = CR5;
            app.Tr = app.robot.model.fkine(app.q);
            app.XEditField.Value = app.Tr(1,4);
            app.YEditField.Value = app.Tr(2,4);
            app.ZEditField.Value = app.Tr(3,4);
            RPY = rad2deg(tr2rpy(app.Tr));
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
            hold on;
            % Loading objects
            app.concretePose1= app.robot.model.base;
            % Load Oven
            [fOven,app.vOven,dataOven] = plyread('oven.ply','tri');
            ovenVertexColours = [dataOven.vertex.red,dataOven.vertex.green,dataOven.vertex.blue] / 255;
            app.oven_h = trisurf(fOven,app.vOven(:,1),app.vOven(:,2),app.vOven(:,3),'FaceVertexCData',ovenVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            updatedOvenPosition = [app.ovenPose*[app.vOven,ones(size(app.vOven,1),1)]']';
            app.oven_h.Vertices = updatedOvenPosition(:,1:3);
            
            % Load Patties
            [fPatty,app.vPatty,dataPatty] = plyread('patty.ply','tri');
            pattyVertexColours = [dataPatty.vertex.red,dataPatty.vertex.green,dataPatty.vertex.blue] / 255;
            app.patty_h1 = trisurf(fPatty,app.vPatty(:,1),app.vPatty(:,2),app.vPatty(:,3),'FaceVertexCData',pattyVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            app.patty_h2 = trisurf(fPatty,app.vPatty(:,1),app.vPatty(:,2),app.vPatty(:,3),'FaceVertexCData',pattyVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            app.patty_h3 = trisurf(fPatty,app.vPatty(:,1),app.vPatty(:,2),app.vPatty(:,3),'FaceVertexCData',pattyVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            app.patty_h4 = trisurf(fPatty,app.vPatty(:,1),app.vPatty(:,2),app.vPatty(:,3),'FaceVertexCData',pattyVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            updatedPattyPosition1 = [app.pattyPose1*[app.vPatty,ones(size(app.vPatty,1),1)]']';
            updatedPattyPosition2 = [app.pattyPose2*[app.vPatty,ones(size(app.vPatty,1),1)]']';
            updatedPattyPosition3 = [app.pattyPose3*[app.vPatty,ones(size(app.vPatty,1),1)]']';
            updatedPattyPosition4 = [app.pattyPose4*[app.vPatty,ones(size(app.vPatty,1),1)]']';
            app.patty_h1.Vertices = updatedPattyPosition1(:,1:3);
            app.patty_h2.Vertices = updatedPattyPosition2(:,1:3);
            app.patty_h3.Vertices = updatedPattyPosition3(:,1:3);
            app.patty_h4.Vertices = updatedPattyPosition4(:,1:3);
            
            % Load Fry basket
            [fFry,app.vFry,dataFry] = plyread('basket.ply','tri');
            fryVertexColours = [dataFry.vertex.red,dataFry.vertex.green,dataFry.vertex.blue] / 255;
            app.fry_h = trisurf(fFry,app.vFry(:,1),app.vFry(:,2),app.vFry(:,3),'FaceVertexCData',fryVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            updatedFryPosition = [app.fryPose*[app.vFry,ones(size(app.vFry,1),1)]']';
            app.fry_h.Vertices = updatedFryPosition(:,1:3);
            
            % Load Spatula
            
            [fSpat,app.vSpat,dataSpat] = plyread('spatula.ply','tri');
            spatVertexColours = [dataSpat.vertex.red,dataSpat.vertex.green,dataSpat.vertex.blue] / 255;
            app.spat_h = trisurf(fSpat,app.vSpat(:,1),app.vSpat(:,2),app.vSpat(:,3),'FaceVertexCData',spatVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            updatedSpatPosition = [app.spatPose*[app.vSpat,ones(size(app.vSpat,1),1)]']';
            app.spat_h.Vertices = updatedSpatPosition(:,1:3);
            
            %Loading floor
            [fFloor,app.vFloor,dataFloor] = plyread('floor.ply','tri');
            floorVertexColours = [dataFloor.vertex.red,dataFloor.vertex.green,dataFloor.vertex.blue] / 255;
            app.floor_h = trisurf(fFloor,app.vFloor(:,1),app.vFloor(:,2),app.vFloor(:,3),'FaceVertexCData',floorVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            floorVertexCount = size(app.vFloor,1);
            updatedFloorPos = [app.floorPose * [app.vFloor,ones(floorVertexCount,1)]']';
            app.floor_h.Vertices = updatedFloorPos(:,1:3);
            
            %Loading Light Curtain
            [fLight,app.vLight,dataLight] = plyread('lightC.ply','tri');
            lightVertexColours = [dataLight.vertex.red,dataLight.vertex.green,dataLight.vertex.blue] / 255;
            app.light_h = trisurf(fLight,app.vLight(:,1),app.vLight(:,2),app.vLight(:,3),'FaceVertexCData',lightVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            lightVertexCount = size(app.vLight,1);
            updatedLightPos = [app.lightPose * [app.vLight,ones(lightVertexCount,1)]']';
            app.light_h.Vertices = updatedLightPos(:,1:3);
            app.light1_h = trisurf(fLight,app.vLight(:,1),app.vLight(:,2),app.vLight(:,3),'FaceVertexCData',lightVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            updatedLightPos1 = [app.lightPose1 * [app.vLight,ones(lightVertexCount,1)]']';
            app.light1_h.Vertices = updatedLightPos1(:,1:3);
            
            %loading Robot Base
            [fCube,app.vCube,dataCube] = plyread('concrete.ply','tri');
            baseVertexColours = [dataCube.vertex.red,dataCube.vertex.green,dataCube.vertex.blue] / 255;
            app.concrete1_h = trisurf(fCube,app.vCube(:,1),app.vCube(:,2),app.vCube(:,3),'FaceVertexCData',baseVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            vertexCount = size(app.vCube,1);
            updatedPosition1 = [app.concretePose1 * [app.vCube,ones(vertexCount,1)]']';
            app.concrete1_h.Vertices = updatedPosition1(:,1:3);
            view(3);
            
            %Load Tony
            [app.fTony,app.vTony,app.dataTony] = plyread('fatTony.ply','tri');
            tonyVertexColours = [app.dataTony.vertex.red,app.dataTony.vertex.green,app.dataTony.vertex.blue] / 255;
            app.tony_h = trisurf(app.fTony,app.vTony(:,1),app.vTony(:,2),app.vTony(:,3),'FaceVertexCData',tonyVertexColours,'EdgeColor','interp','EdgeLighting','flat');
            app.tonyVertexCount = size(app.vTony,1);
            updatedTonyPos = [app.tonyPose * [app.vTony,ones(app.tonyVertexCount,1)]']';
            app.tony_h.Vertices = updatedTonyPos(:,1:3);
            app.vTony = app.tony_h.Vertices;
            app.fNormalTony = zeros(size(app.fTony,1),3);
            for faceIndex = 1:size(app.fTony,1)
                v1 = app.vTony(app.fTony(faceIndex,1)',:);
                v2 = app.vTony(app.fTony(faceIndex,2)',:);
                v3 = app.vTony(app.fTony(faceIndex,3)',:);
                app.fNormalTony(faceIndex,:) = unit(cross(v2-v1,v3-v1));
            end


            drawnow();
            
            
        end

        % Value changing function: Q1Slider
        function Q1SliderValueChanging(app, event)
            app.q(1) = deg2rad(event.Value);
            app.robot.model.animate(app.q);
            app.Tr = app.robot.model.fkine(app.q);
            RPY = rad2deg(tr2rpy(app.Tr));
            app.XEditField.Value = app.Tr(1,4);
            app.YEditField.Value = app.Tr(2,4);
            app.ZEditField.Value = app.Tr(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
        end

        % Value changing function: Q2Slider
        function Q2SliderValueChanging(app, event)
            app.q(2) = deg2rad(event.Value);
            app.robot.model.animate(app.q);
            app.Tr = app.robot.model.fkine(app.q);
            RPY = rad2deg(tr2rpy(app.Tr));
            app.XEditField.Value = app.Tr(1,4);
            app.YEditField.Value = app.Tr(2,4);
            app.ZEditField.Value = app.Tr(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
        end

        % Value changing function: Q3Slider
        function Q3SliderValueChanging(app, event)
            app.q(3) = deg2rad(event.Value);
            app.robot.model.animate(app.q);
            app.Tr = app.robot.model.fkine(app.q);
            RPY = rad2deg(tr2rpy(app.Tr));
            app.XEditField.Value = app.Tr(1,4);
            app.YEditField.Value = app.Tr(2,4);
            app.ZEditField.Value = app.Tr(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
        end

        % Value changing function: Q4Slider
        function Q4SliderValueChanging(app, event)
            app.q(4) = deg2rad(event.Value);
            app.robot.model.animate(app.q);
            app.Tr = app.robot.model.fkine(app.q);
            RPY = rad2deg(tr2rpy(app.Tr));
            app.XEditField.Value = app.Tr(1,4);
            app.YEditField.Value = app.Tr(2,4);
            app.ZEditField.Value = app.Tr(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
        end

        % Value changing function: Q5Slider
        function Q5SliderValueChanging(app, event)
            app.q(5) = deg2rad(event.Value);
            app.robot.model.animate(app.q);
            app.Tr = app.robot.model.fkine(app.q);
            RPY = rad2deg(tr2rpy(app.Tr));
            app.XEditField.Value = app.Tr(1,4);
            app.YEditField.Value = app.Tr(2,4);
            app.ZEditField.Value = app.Tr(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
        end

        % Value changing function: Q6Slider
        function Q6SliderValueChanging(app, event)
            app.q(6) = deg2rad(event.Value);
            app.robot.model.animate(app.q);
            app.Tr = app.robot.model.fkine(app.q);
            RPY = rad2deg(tr2rpy(app.Tr));
            app.XEditField.Value = app.Tr(1,4);
            app.YEditField.Value = app.Tr(2,4);
            app.ZEditField.Value = app.Tr(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.q = zeros(1,6);
            app.robot.model.animate(app.q);
            app.Tr = app.robot.model.fkine(app.q);
            RPY = rad2deg(tr2rpy(app.Tr));
            app.XEditField.Value = app.Tr(1,4);
            app.YEditField.Value = app.Tr(2,4);
            app.ZEditField.Value = app.Tr(3,4);
            app.RollEditField.Value = RPY(1);
            app.PitchEditField.Value =RPY(2);
            app.YawEditField.Value = RPY(3);
        end

        % Button pushed function: RMRCButton
        function RMRCButtonPushed(app, event)
            T2 = rpy2tr(deg2rad(app.RollEditField.Value),deg2rad(app.PitchEditField.Value),deg2rad(app.YawEditField.Value))*transl(app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value);
            [qMatrix] = RMRC(app.robot,app.Tr,T2,app.q);
            app.q = qMatrix(end,:);
            app.Tr = T2;
            app.Q1Slider.Value = app.q(1);
            app.Q2Slider.Value = app.q(2);
            app.Q3Slider.Value = app.q(3);
            app.Q4Slider.Value = app.q(4);
            app.Q5Slider.Value = app.q(5);
            app.Q6Slider.Value = app.q(6);
            for i = 1 : size(qMatrix,1)
                while app.EmergencyStopSwitch.Value == "Stop"
                    pause(0.01)
                    if app.EmergencyStopSwitch.Value == "Run"
                        break;
                    end
                end
                app.robot.model.animate(qMatrix(i,:));
                pause(0.01);
            end
        end

        % Value changed function: ControlModeSwitch
        function ControlModeSwitchValueChanged(app, event)
            %% Reference UTSOnline, Week11 Material
            id = 1;
            joy = vrjoystick(id);
            caps(joy)
            app.robot.model.animate(app.q);          % Plot robot in initial configuration
            app.robot.model.delay = 0.001;
            dt = 0.15;      % Set time step for simulation (seconds
            lambda = 0.005;
            Ka = diag([0.3 0.3 0.3 0.5 0.5 0.5]); % admittance gain matrix
            while app.ControlModeSwitch.Value == "Joystick"
                % read joystick
                [axes, buttons, ~] = read(joy);
                while app.EmergencyStopSwitch.Value == "Stop"
                    pause(0.01)
                    if app.EmergencyStopSwitch.Value == "Run"
                        break;
                    end
                end
                
                % -------------------------------------------------------------
                % YOUR CODE GOES HERE
                % 1 - turn joystick input into an end-effector velocity command
                Kv = 0.2; % linear velocity gain
                Kw = 1.0; % angular velocity gain
                
                vx = Kv*axes(1);
                vy = Kv*axes(2);
                vz = Kv*(buttons(1)-buttons(4));
                
                wx = Kw*axes(4);
                wy = Kw*axes(5);
                wz = Kw*axes(3);
                
                dx = [vx;vy;vz;wx;wy;wz]; % combined velocity vector
                
                dx = Ka*dx; % convert wrench into end-effector velocity command
                % 2 - use J inverse to calculate joint velocity
                J = app.robot.model.jacob0(app.q);
                Jinv_dls = inv((J'*J)+lambda^2*eye(6))*J';
                dq = Jinv_dls*dx;
                
                % 3 - apply joint velocity to step robot joint angles
                app.q = app.q + dq'*dt;
                
                % -------------------------------------------------------------
                
                % Update plot
                app.robot.model.animate(app.q);
            end
        end

        % Callback function
        function EmmergencyStopButtonPushed(app, event)
            
            if app.ES == 1
                app.ES = 0
            else app.ES = 1
            end


        end

        % Button pushed function: FlipthePattiesButton
        function FlipthePattiesButtonPushed(app, event)
            %% Compute qMatrices
            
            %% qn to Fry
            TrN = app.robot.model.fkine(app.qn);
            qN2Fry = RMRC(app.robot,TrN,app.fryPose*troty(pi)*trotz(pi/2),app.qn);
            %robot.model.plot(qN2Fry,'fps',300)
            
            %% qFry to Down
            Tdown = app.fryPose*troty(pi)*trotz(pi/2)*transl(0,0,0.15);
            qFry2Down = RMRC(app.robot,app.robot.model.fkine(qN2Fry(end,:)),Tdown,qN2Fry(end,:)); % robot qMatrix
            %robot.model.plot(qFry2Down,'fps',100)
            
            %% q Down to qSpat
            qDown2Spat = RMRC(app.robot,app.robot.model.fkine(qFry2Down(end,:)),app.spatPose*troty(pi),qFry2Down(end,:));
            %robot.model.plot(qDown2Spat,'fps',100)
            
            %% Flip 1
            % Spat to p1
            TrPickUpReady1 = app.pattyPose1*transl(0,-0.15,0)*troty(pi);
            qSpat2p1 = RMRC(app.robot,app.robot.model.fkine(qDown2Spat(end,:)),TrPickUpReady1,qDown2Spat(end,:));
            %robot.model.plot(qSpat2p1,'fps',200)
            % p1 under
            qUnder1 = RMRC(app.robot,app.robot.model.fkine(qSpat2p1(end,:)),app.pattyPose1*troty(pi)*transl(0,-0.08,-0.01),qSpat2p1(end,:));
            %robot.model.plot(qUnder1,'fps',200);
            
            toUnder1 = vertcat(qSpat2p1,qUnder1);
            
            % p1 pickup
            qPickUp1 = RMRC(app.robot,app.robot.model.fkine(qUnder1(end,:)),app.pattyPose1*troty(pi)*transl(0,-0.08,-0.1),qUnder1(end,:));
            %robot.model.plot(qPickUp1,'fps',200);
            
            % p1 Flip
            qFlip1 = RMRC(app.robot,app.robot.model.fkine(qPickUp1(end,:)),app.robot.model.fkine(qPickUp1(end,:))*troty(-pi/4)*transl(0,-0.05,0),qPickUp1(end,:));
            %robot.model.plot(qFlip1,'fps',200);
            
            upFlip1 = vertcat(qPickUp1,qFlip1);
            
            %% Flip 2
            % p1 Flip to p2
            TrPickUpReady2 = app.pattyPose2*transl(0,-0.15,0)*troty(pi);
            qp12p2 = RMRC(app.robot,app.robot.model.fkine(qFlip1(end,:)),TrPickUpReady2,qFlip1(end,:));
            %robot.model.plot(qp12p2,'fps',200)
            % p2 under
            qUnder2 = RMRC(app.robot,app.robot.model.fkine(qp12p2(end,:)),app.pattyPose2*troty(pi)*transl(0,-0.08,-0.01),qp12p2(end,:));
            %robot.model.plot(qUnder2,'fps',200);
            
            toUnder2 = vertcat(qp12p2,qUnder2);
            
            % p2 pickup
            qPickUp2 = RMRC(app.robot,app.robot.model.fkine(qUnder2(end,:)),app.pattyPose2*troty(pi)*transl(0,-0.08,-0.1),qUnder2(end,:));
            %robot.model.plot(qPickUp2,'fps',200);
            % p2 Flip
            
            qFlip2 = repmat(qPickUp2(end,:),150,1);
            prev = qPickUp2(end,:);
            qFlip2(1,:) = prev;
            for i = 2:150
                qFlip2(i,5) = qFlip2(i-1,5)-(deg2rad(90-74)/100);
            end
            %robot.model.plot(qFlip2,'fps',200);
            
            upFlip2 = vertcat(qPickUp2,qFlip2);
            %% Flip 3
            % p2 Flip to p3
            TrPickUpReady3 = app.pattyPose3*transl(0,-0.15,0)*troty(pi);
            qp22p3 = RMRC(app.robot,app.robot.model.fkine(qFlip2(end,:)),TrPickUpReady3,qFlip2(end,:));
            %robot.model.plot(qp22p3,'fps',200)
            % p3 under
            qUnder3 = RMRC(app.robot,app.robot.model.fkine(qp22p3(end,:)),app.pattyPose3*troty(pi)*transl(0,-0.08,-0.01),qp22p3(end,:));
            %robot.model.plot(qUnder3,'fps',200);
            
            toUnder3 = vertcat(qp22p3,qUnder3);
            
            % p3 pickup
            qPickUp3 = RMRC(app.robot,app.robot.model.fkine(qUnder3(end,:)),app.pattyPose3*troty(pi)*transl(0,-0.08,-0.1),qUnder3(end,:));
            %robot.model.plot(qPickUp3,'fps',200);
            
            % p3 Flip
            qFlip3 = repmat(qPickUp3(end,:),150,1);
            prev = qPickUp3(end,:);
            qFlip3(1,:) = prev;
            for i = 2:150
                qFlip3(i,5) = qFlip3(i-1,5)-(deg2rad(90-74)/100);
            end
            %robot.model.plot(qFlip3,'fps',200);
            
            upFlip3 = vertcat(qPickUp3,qFlip3);
            %% Flip 4
            % p4 Flip to p4
            TrPickUpReady4 = app.pattyPose4*transl(0,-0.15,0)*troty(pi);
            qp32p4 = RMRC(app.robot,app.robot.model.fkine(qFlip3(end,:)),TrPickUpReady4,qFlip3(end,:));
            %robot.model.plot(qp32p4,'fps',200)
            % p4 under
            qUnder4 = RMRC(app.robot,app.robot.model.fkine(qp32p4(end,:)),app.pattyPose4*troty(pi)*transl(0,-0.08,-0.01),qp32p4(end,:));
            %robot.model.plot(qUnder4,'fps',200);
            
            toUnder4 = vertcat(qp32p4,qUnder4);
            
            % p4 pickup
            qPickUp4 = RMRC(app.robot,app.robot.model.fkine(qUnder4(end,:)),app.pattyPose4*troty(pi)*transl(0,-0.08,-0.1),qUnder4(end,:));
            %robot.model.plot(qPickUp4,'fps',200);
            % p4 Flip
            qFlip4 = repmat(qPickUp4(end,:),150,1);
            prev = qPickUp4(end,:);
            qFlip4(1,:) = prev;
            for i = 2:150
                qFlip4(i,5) = qFlip4(i-1,5)-(deg2rad(90-74)/100);
            end
            %robot.model.plot(qFlip4,'fps',200);
            
            upFlip4 = vertcat(qPickUp4,qFlip4);
            
            %% Pickup Fry
            % Drop Spatula
            IspatPose = transl(0.35,-0.7,0.52)*trotz(-pi/2)*troty(pi);
            q42Spat = RMRC(app.robot,app.robot.model.fkine(qFlip4(end,:)),IspatPose,qFlip4(end,:));
            
            % Goto Fry
            qSpat2Fry = RMRC(app.robot,app.robot.model.fkine(q42Spat(end,:)),Tdown,q42Spat(end,:));
            
            % pick up fry
            qPickUpFry = RMRC(app.robot,app.robot.model.fkine(qSpat2Fry(end,:)),app.robot.model.fkine(qN2Fry(end,:)),qSpat2Fry(end,:));
            
            % Goback to qn
            qNorm = RMRC(app.robot,app.robot.model.fkine(qPickUpFry(end,:)),app.robot.model.fkine(app.qn),qPickUpFry(end,:));


            %% Run through the work
            sUnit = 3; % Number of step every loop
            %axis([-1.5 1.5 -1.5 1.5 -0.05 2]);
            %                         app.tonyPose
            %             app.tonyPose(2,end) = event.Value;
            %             app.tonyPose
            %             updatedTonyPos = [app.tonyPose * [app.vTony,ones(app.tonyVertexCount,1)]']';
            %             app.tony_h.Vertices = updatedTonyPos(:,1:3);
            %             drawnow();
            %             app.vTony = app.tony_h.Vertices;
            %             app.fNormalTony = zeros(size(app.fTony,1),3);
            %             for faceIndex = 1:size(app.fTony,1)
            %                 v1 = app.vTony(app.fTony(faceIndex,1)',:);
            %                 v2 = app.vTony(app.fTony(faceIndex,2)',:);
            %                 v3 = app.vTony(app.fTony(faceIndex,3)',:);
            %                 app.fNormalTony(faceIndex,:) = unit(cross(v2-v1,v3-v1));
            %             end
            %Take the fry
            for i = 1: size(qN2Fry,1)
                if i < size(qN2Fry,1)-2
                    result = IsCollision(app,app.robot.model,qN2Fry(i+2,:),app.fTony,app.vTony,app.fNormalTony);
                end
                if result == true
                    app.EmergencyStopSwitch.Value = "Stop"
                    else
                app.EmergencyStopSwitch.Value = "Run"
                end
                app.robot.model.animate(qN2Fry(i,:));
                pause(0.01);
                e_Stop(app);
            end
            %Put Fry down
            for i = 1 :sUnit: size(qFry2Down,1)
                if i < size(qFry2Down,1)-2
                    result = IsCollision(app,app.robot.model,qFry2Down(i+2,:),app.fTony,app.vTony,app.fNormalTony);
                end
                if result == true
                    app.EmergencyStopSwitch.Value = "Stop"
                                        else
                app.EmergencyStopSwitch.Value = "Run"
                end
                app.robot.model.animate(qFry2Down(i,:));
                app.fryPose = app.robot.model.fkine(qFry2Down(i,:))*trotz(-pi/2)*troty(pi);
                updatedFryPosition = [app.fryPose*[app.vFry,ones(size(app.vFry,1),1)]']';
                app.fry_h.Vertices = updatedFryPosition(:,1:3);
                drawnow();
                e_Stop(app);
                pause(0.001);
            end
            
            %Take spatula
            
            for i = 1: size(qDown2Spat,1)
                if i < size(qDown2Spat,1)-2
                    result = IsCollision(app,app.robot.model,qDown2Spat(i+2,:),app.fTony,app.vTony,app.fNormalTony);
                end
                if result == true
                    app.EmergencyStopSwitch.Value = "Stop"
                                        else
                app.EmergencyStopSwitch.Value = "Run"
                end
                app.robot.model.animate(qDown2Spat(i,:));
                pause(0.001);
                e_Stop(app);
            end
            
            %Flip the meat1
            
            for i = 1 :sUnit: size(toUnder1,1)
                if i < size(toUnder1,1)-2
                    result = IsCollision(app,app.robot.model,toUnder1(i+2,:),app.fTony,app.vTony,app.fNormalTony);
                end
                if result == true
                    app.EmergencyStopSwitch.Value = "Stop"
                                        else
                app.EmergencyStopSwitch.Value = "Run"
                end
                app.robot.model.animate(toUnder1(i,:));
                app.spatPose = app.robot.model.fkine(toUnder1(i,:))*troty(pi);
                updatedSpatPosition = [app.spatPose*[app.vSpat,ones(size(app.vSpat,1),1)]']';
                app.spat_h.Vertices = updatedSpatPosition(:,1:3);
                drawnow();
                e_Stop(app);
                pause(0.001);
            end
            
            for i=1:sUnit: size(upFlip1,1)
                if i < size(upFlip1,1)-2
                    result = IsCollision(app,app.robot.model,upFlip1(i+2,:),app.fTony,app.vTony,app.fNormalTony);
                end
                if result == true
                    app.EmergencyStopSwitch.Value = "Stop"
                                        else
                app.EmergencyStopSwitch.Value = "Run"
                end
                app.robot.model.animate(upFlip1(i,:));
                app.spatPose = app.robot.model.fkine(upFlip1(i,:))*troty(pi);
                updatedSpatPosition = [app.spatPose*[app.vSpat,ones(size(app.vSpat,1),1)]']';
                app.pattyPose1 = app.robot.model.fkine(upFlip1(i,:))*app.EE2Patty;
                updatedPattyPosition1 = [app.pattyPose1*[app.vPatty,ones(size(app.vPatty,1),1)]']';
                app.patty_h1.Vertices = updatedPattyPosition1(:,1:3);
                app.spat_h.Vertices = updatedSpatPosition(:,1:3);
                drawnow();
                pause(0.001);
                e_Stop(app);
                
            end
            
            % meat1 dropping
            Tmeat = mTraj(app,app.pattyPose1,transl(0.55,-0.55,0.5)*trotz(-pi/2)*trotx(pi));
            for i = 1:size(Tmeat,1)
                app.pattyPose1 = [ Tmeat(i,1,1),Tmeat(i,1,2),Tmeat(i,1,3),Tmeat(i,1,4);...
                    , Tmeat(i,2,1),Tmeat(i,2,2),Tmeat(i,2,3),Tmeat(i,2,4);...
                    , Tmeat(i,3,1),Tmeat(i,3,2),Tmeat(i,3,3),Tmeat(i,3,4);...
                    , Tmeat(i,4,1),Tmeat(i,4,2),Tmeat(i,4,3),Tmeat(i,4,4)];
                updatedPattyPosition1 = [app.pattyPose1*[app.vPatty,ones(size(app.vPatty,1),1)]']';
                app.patty_h1.Vertices = updatedPattyPosition1(:,1:3);
                drawnow();
                pause(0.001);
                e_Stop(app);
            end
            %% Flip the meat2
            % move to patty 2
            for i = 1 :sUnit: size(toUnder2,1)
                if i < size(toUnder2,1)-2
                    result = IsCollision(app,app.robot.model,toUnder2(i+2,:),app.fTony,app.vTony,app.fNormalTony);
                end
                if result == true
                    app.EmergencyStopSwitch.Value = "Stop"
                                        else
                app.EmergencyStopSwitch.Value = "Run"
                end
                app.robot.model.animate(toUnder2(i,:));
                app.spatPose = app.robot.model.fkine(toUnder2(i,:))*troty(pi);
                updatedSpatPosition = [app.spatPose*[app.vSpat,ones(size(app.vSpat,1),1)]']';
                app.spat_h.Vertices = updatedSpatPosition(:,1:3);
                drawnow();
                pause(0.001);
                e_Stop(app);
            end
            
            for i=1:sUnit: size(upFlip2,1)
                if i < size(upFlip2,1)-2
                    result = IsCollision(app,app.robot.model,upFlip2(i+2,:),app.fTony,app.vTony,app.fNormalTony);
                end
                if result == true
                    app.EmergencyStopSwitch.Value = "Stop"
                                        else
                app.EmergencyStopSwitch.Value = "Run"
                end
                app.robot.model.animate(upFlip2(i,:));
                app.spatPose = app.robot.model.fkine(upFlip2(i,:))*troty(pi);
                updatedSpatPosition = [app.spatPose*[app.vSpat,ones(size(app.vSpat,1),1)]']';
                app.pattyPose2 = app.robot.model.fkine(upFlip2(i,:))*app.EE2Patty;
                updatedPattyPosition2 = [app.pattyPose2*[app.vPatty,ones(size(app.vPatty,1),1)]']';
                app.patty_h2.Vertices = updatedPattyPosition2(:,1:3);
                app.spat_h.Vertices = updatedSpatPosition(:,1:3);
                drawnow();
                pause(0.001);
                e_Stop(app);
            end
            
            % meat2 dropping
            Tmeat = mTraj(app,app.pattyPose2,transl(0.55,-0.2,0.5)*trotz(-pi/2)*trotx(pi));
            for i = 1:size(Tmeat,1)
                app.pattyPose2 = [ Tmeat(i,1,1),Tmeat(i,1,2),Tmeat(i,1,3),Tmeat(i,1,4);...
                    , Tmeat(i,2,1),Tmeat(i,2,2),Tmeat(i,2,3),Tmeat(i,2,4);...
                    , Tmeat(i,3,1),Tmeat(i,3,2),Tmeat(i,3,3),Tmeat(i,3,4);...
                    , Tmeat(i,4,1),Tmeat(i,4,2),Tmeat(i,4,3),Tmeat(i,4,4)];
                updatedPattyPosition2 = [app.pattyPose2*[app.vPatty,ones(size(app.vPatty,1),1)]']';
                app.patty_h2.Vertices = updatedPattyPosition2(:,1:3);
                drawnow();
                pause(0.001);
                e_Stop(app);
            end
            
            
            %% Flip the meat3
            
            % move to patty 3
            for i = 1 :sUnit: size(toUnder3,1)
                if i < size(toUnder3,1)-2
                    result = IsCollision(app,app.robot.model,toUnder3(i+2,:),app.fTony,app.vTony,app.fNormalTony);
                end
                if result == true
                    app.EmergencyStopSwitch.Value = "Stop"
                                        else
                app.EmergencyStopSwitch.Value = "Run"
                end
                app.robot.model.animate(toUnder3(i,:));
                app.spatPose = app.robot.model.fkine(toUnder3(i,:))*troty(pi);
                updatedSpatPosition = [app.spatPose*[app.vSpat,ones(size(app.vSpat,1),1)]']';
                app.spat_h.Vertices = updatedSpatPosition(:,1:3);
                drawnow();
                pause(0.001);
                e_Stop(app);
            end
            
            for i=1:sUnit: size(upFlip3,1)
                if i < size(upFlip3,1)-2
                    result = IsCollision(app,app.robot.model,upFlip3(i+2,:),app.fTony,app.vTony,app.fNormalTony);
                end
                if result == true
                    app.EmergencyStopSwitch.Value = "Stop"
                                        else
                app.EmergencyStopSwitch.Value = "Run"
                end
                app.robot.model.animate(upFlip3(i,:));
                app.spatPose = app.robot.model.fkine(upFlip3(i,:))*troty(pi);
                updatedSpatPosition = [app.spatPose*[app.vSpat,ones(size(app.vSpat,1),1)]']';
                app.pattyPose3 = app.robot.model.fkine(upFlip3(i,:))*app.EE2Patty;
                updatedPattyPosition3 = [app.pattyPose3*[app.vPatty,ones(size(app.vPatty,1),1)]']';
                app.patty_h3.Vertices = updatedPattyPosition3(:,1:3);
                app.spat_h.Vertices = updatedSpatPosition(:,1:3);
                drawnow();
                pause(0.001);
                e_Stop(app);
            end
            
            % meat3 dropping
            Tmeat = mTraj(app,app.pattyPose3,transl(0.7,-0.2,0.5)*trotz(-pi/2)*trotx(pi));
            for i = 1:size(Tmeat,1)
                app.pattyPose3 = [ Tmeat(i,1,1),Tmeat(i,1,2),Tmeat(i,1,3),Tmeat(i,1,4);...
                    , Tmeat(i,2,1),Tmeat(i,2,2),Tmeat(i,2,3),Tmeat(i,2,4);...
                    , Tmeat(i,3,1),Tmeat(i,3,2),Tmeat(i,3,3),Tmeat(i,3,4);...
                    , Tmeat(i,4,1),Tmeat(i,4,2),Tmeat(i,4,3),Tmeat(i,4,4)];
                updatedPattyPosition3 = [app.pattyPose3*[app.vPatty,ones(size(app.vPatty,1),1)]']';
                app.patty_h3.Vertices = updatedPattyPosition3(:,1:3);
                drawnow();
                pause(0.001);
                e_Stop(app);
            end
            
            %% Flip the meat4
            
            % move to patty 4
            for i = 1 :sUnit: size(toUnder4,1)
                if i < size(toUnder4,1)-2
                    result = IsCollision(app,app.robot.model,toUnder4(i+2,:),app.fTony,app.vTony,app.fNormalTony);
                end
                if result == true
                    app.EmergencyStopSwitch.Value = "Stop"
                                        else
                app.EmergencyStopSwitch.Value = "Run"
                end
                app.robot.model.animate(toUnder4(i,:));
                app.spatPose = app.robot.model.fkine(toUnder4(i,:))*troty(pi);
                updatedSpatPosition = [app.spatPose*[app.vSpat,ones(size(app.vSpat,1),1)]']';
                app.spat_h.Vertices = updatedSpatPosition(:,1:3);
                drawnow();
                pause(0.001);
                e_Stop(app);
            end
            
            for i=1:sUnit: size(upFlip4,1)
                if i < size(upFlip4,1)-2
                    result = IsCollision(app,app.robot.model,upFlip4(i+2,:),app.fTony,app.vTony,app.fNormalTony);
                end
                if result == true
                    app.EmergencyStopSwitch.Value = "Stop"
                                        else
                app.EmergencyStopSwitch.Value = "Run"
                end
                app.robot.model.animate(upFlip4(i,:));
                app.spatPose = app.robot.model.fkine(upFlip4(i,:))*troty(pi);
                updatedSpatPosition = [app.spatPose*[app.vSpat,ones(size(app.vSpat,1),1)]']';
                app.pattyPose4 = app.robot.model.fkine(upFlip4(i,:))*app.EE2Patty;
                updatedPattyPosition4 = [app.pattyPose4*[app.vPatty,ones(size(app.vPatty,1),1)]']';
                app.patty_h4.Vertices = updatedPattyPosition4(:,1:3);
                app.spat_h.Vertices = updatedSpatPosition(:,1:3);
                drawnow();
                pause(0.001);
                e_Stop(app);
            end
            
            % meat4 dropping
            Tmeat = mTraj(app,app.pattyPose4,transl(0.7,-0.55,0.5)*trotz(-pi/2)*trotx(pi));
            for i = 1:size(Tmeat,1)
                app.pattyPose4 = [ Tmeat(i,1,1),Tmeat(i,1,2),Tmeat(i,1,3),Tmeat(i,1,4);...
                    , Tmeat(i,2,1),Tmeat(i,2,2),Tmeat(i,2,3),Tmeat(i,2,4);...
                    , Tmeat(i,3,1),Tmeat(i,3,2),Tmeat(i,3,3),Tmeat(i,3,4);...
                    , Tmeat(i,4,1),Tmeat(i,4,2),Tmeat(i,4,3),Tmeat(i,4,4)];
                updatedPattyPosition4 = [app.pattyPose4*[app.vPatty,ones(size(app.vPatty,1),1)]']';
                app.patty_h4.Vertices = updatedPattyPosition4(:,1:3);
                drawnow();
                pause(0.001);
                e_Stop(app);
            end
            
            %Drop spatula
            for i = 1 :sUnit: size(q42Spat,1)
                if i < size(q42Spat,1)-2
                    result = IsCollision(app,app.robot.model,q42Spat(i+2,:),app.fTony,app.vTony,app.fNormalTony);
                end
                if result == true
                    app.EmergencyStopSwitch.Value = "Stop"
                                        else
                app.EmergencyStopSwitch.Value = "Run"
                end
                app.robot.model.animate(q42Spat(i,:));
                app.spatPose = app.robot.model.fkine(q42Spat(i,:))*troty(pi);
                updatedSpatPosition = [app.spatPose*[app.vSpat,ones(size(app.vSpat,1),1)]']';
                app.spat_h.Vertices = updatedSpatPosition(:,1:3);
                drawnow();
                pause(0.001);
                e_Stop(app);
            end
            
            %Go to fry
            for i = 1: size(qSpat2Fry,1)
                if i < size(qSpat2Fry,1)-2
                    result = IsCollision(app,app.robot.model,qSpat2Fry(i+2,:),app.fTony,app.vTony,app.fNormalTony);
                end
                if result == true
                    app.EmergencyStopSwitch.Value = "Stop"
                                        else
                app.EmergencyStopSwitch.Value = "Run"
                end
                app.robot.model.animate(qSpat2Fry(i,:));
                pause(0.001);
                e_Stop(app);
            end
            
            %Pickup Fry
            for i = 1 :sUnit: size(qPickUpFry,1)
                if i < size(qPickUpFry,1)-2
                    result = IsCollision(app,app.robot.model,qPickUpFry(i+2,:),app.fTony,app.vTony,app.fNormalTony);
                end
                if result == true
                    app.EmergencyStopSwitch.Value = "Stop"
                                        else
                app.EmergencyStopSwitch.Value = "Run"
                end
                app.robot.model.animate(qPickUpFry(i,:));
                app.fryPose = app.robot.model.fkine(qPickUpFry(i,:))*trotz(-pi/2)*troty(pi);
                updatedFryPosition = [app.fryPose*[app.vFry,ones(size(app.vFry,1),1)]']';
                app.fry_h.Vertices = updatedFryPosition(:,1:3);
                drawnow();
                pause(0.001);
                e_Stop(app);
            end
            
            %Go back to Ready Position
            for i = 1: size(qNorm,1)
                if i < size(qNorm,1)-2
                    result = IsCollision(app,app.robot.model,qNorm(i+2,:),app.fTony,app.vTony,app.fNormalTony);
                end
                if result == true
                    app.EmergencyStopSwitch.Value = "Stop"
                                        else
                app.EmergencyStopSwitch.Value = "Run"
                end
                app.robot.model.animate(qNorm(i,:));
                pause(0.001);
                e_Stop(app);
            end


        end

        % Value changing function: WorkerPositionSlider
        function WorkerPositionSliderValueChanging(app, event)
            
            app.tonyPose
            app.tonyPose(2,end) = event.Value;
            app.tonyPose
            updatedTonyPos = [app.tonyPose * [app.vTony,ones(app.tonyVertexCount,1)]']';
            app.tony_h.Vertices = updatedTonyPos(:,1:3);
            drawnow();
            app.vTony = app.tony_h.Vertices;
            app.fNormalTony = zeros(size(app.fTony,1),3);
            for faceIndex = 1:size(app.fTony,1)
                v1 = app.vTony(app.fTony(faceIndex,1)',:);
                v2 = app.vTony(app.fTony(faceIndex,2)',:);
                v3 = app.vTony(app.fTony(faceIndex,3)',:);
                app.fNormalTony(faceIndex,:) = unit(cross(v2-v1,v3-v1));
            end
            
            
            result1 =  IsCollisionForObjects(app,app.lightPose1,app.fTony,app.vTony,app.fNormalTony,false);
            
            if result1 == true
                app.EmergencyStopSwitch.Value = "Stop"
            else
                app.EmergencyStopSwitch.Value = "Run"
            end


        end

        % Value changed function: VisualServoingSwitch
        function VisualServoingSwitchValueChanged(app, event)
            value = app.VisualServoingSwitch.Value;
            pause(0.1);
            if value == "On"
                app.P_P = patch('Faces',app.facesP,'Vertices',app.P','FaceColor','red','lineStyle','none');
                Tc0 = app.robot.model.fkine(app.qCam);
                app.robot.model.animate(app.qCam');
                app.cam.T = Tc0;
                app.cam.plot_camera('Tcam',Tc0, 'label','scale',0.1);
                lighting gouraud
                light
                hold on
                p = app.cam.plot(app.P, 'Tcam', Tc0);
                
                %camera view and plotting
                app.cam.clf()
                app.cam.plot(app.pStar, '*'); % create the camera view
                app.cam.hold(true);
                app.cam.plot(app.P, 'Tcam', Tc0, 'o'); % create the camera view
                pause(2)
                app.cam.hold(true);
                app.cam.plot(app.P);
                ksteps = 0;
                epsilon = 0.3;
                
                while true && value == "On"
                    ksteps = ksteps + 1;
                    
                    % compute the view of the camera
                    uv = app.cam.plot(app.P);
                    
                    % compute image plane error as a column
                    e = app.pStar-uv;   % feature error
                    e = e(:);
                    Zest = [];
                    
                    % compute the Jacobian
                    if isempty(app.depth)
                        % exact depth from simulation (not possible in practice)
                        pt = homtrans(inv(Tcam), app.P);
                        J = app.cam.visjac_p(uv, pt(3,:) );
                    elseif ~isempty(Zest)
                        J = app.cam.visjac_p(uv, Zest);
                    else
                        J = app.cam.visjac_p(uv, app.depth );
                    end
                    
                    % compute the velocity of camera in camera frame
                    try
                        v = app.lambda * pinv(J) * e;%%x dot
                    catch
                        status = -1;
                        return
                    end
                    fprintf('v: %.3f %.3f %.3f %.3f %.3f %.3f\n', v);
                    
                    %compute robot's Jacobian and inverse
                    J20 = app.robot.model.jacob0(app.qCam);
                    J2 = app.robot.model.jacobn(app.qCam);
                    m = sqrt(det(J20*J20'));
                    if m < epsilon  % If manipulability is less than given threshold
                        a = (1 - m/epsilon)*5E-2;
                    else
                        a = 0;
                    end
                    Jinv = pinv(J2'*J2 + a*eye(6))*J2';
                    %Jinv = pinv(J2);
                    % get joint velocities
                    qp = Jinv*v;%%qdot
                    
                    
                    %     %Maximum angular velocity cannot exceed 180 degrees/s
                    %                     ind=find(qp>pi);
                    %                     if ~isempty(ind)
                    %                         qp(ind)=pi;
                    %                     end
                    %                     ind=find(qp<-pi);
                    %                     if ~isempty(ind)
                    %                         qp(ind)=-pi;
                    %                     end
                    
                    %Update joints
                    qC = app.qCam + (1/app.fps)*qp;
                    app.robot.model.animate(qC');
                    
                    %Get camera location
                    Tc = app.robot.model.fkine(qC);
                    app.cam.T = Tc;
                    
                    drawnow
                    
                    pause(1/app.fps)
                    
                    if ~isempty(10000) && (ksteps > 10000)
                        break;
                    end
                    
                    %update current joint position
                    app.qCam = app.qCam + (1/app.fps)*qp;
                    value = app.VisualServoingSwitch.Value;
                    if value == "Off"
                        delete(app.cam);
                        break;
                    end
                    %                     while app.EmergencyStopSwitch.Value == "Stop"
                    %                         pause(0.01);
                    %                     end
                end %loop finishes
            end
            if value == "Off"
                delete(app.cam);
            end
        end

        % Button pushed function: MoveWarningSymbolForwardButton
        function MoveWarningSymbolForwardButtonPushed(app, event)
            if app.VisualServoingSwitch.Value == "On"
                try delete(app.P_P); end;
                app.P(2,:) = app.P(2,:)- 0.02;
                app.P_P = patch('Faces',app.facesP,'Vertices',app.P','FaceColor','red','lineStyle','none');
                pause(0.001);
            end
        end

        % Button pushed function: MoveWarningSymbolBackwardButton
        function MoveWarningSymbolBackwardButtonPushed(app, event)
            if app.VisualServoingSwitch.Value == "On"
                try delete(app.P_P); end;
                app.P(2,:) = app.P(2,:)+ 0.02;
                app.P_P = patch('Faces',app.facesP,'Vertices',app.P','FaceColor','red','lineStyle','none');
                pause(0.001);
            end
        end

        % Button pushed function: RotateWarningSymbolClockwiseButton
        function RotateWarningSymbolClockwiseButtonPushed(app, event)
            if app.VisualServoingSwitch.Value == "On"
                try delete(app.P_P); end;
                app.P = (app.P)'*roty(deg2rad(2));
                app.P= app.P';
                app.P_P = patch('Faces',app.facesP,'Vertices',app.P','FaceColor','red','lineStyle','none');
                pause(0.001);
            end
        end

        % Button pushed function: 
        % RotateWarningSymbolCounterClockwiseButton
        function RotateWarningSymbolCounterClockwiseButtonPushed(app, event)
            if app.VisualServoingSwitch.Value == "On"
                try delete(app.P_P); end;
                app.P = (app.P)'*roty(-deg2rad(2));
                app.P= app.P';
                app.P_P = patch('Faces',app.facesP,'Vertices',app.P','FaceColor','red','lineStyle','none');
                pause(0.001);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.JointControlLabel = uilabel(app.UIFigure);
            app.JointControlLabel.Text = 'Joint Control';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DestinationLabel = uilabel(app.UIFigure);
            app.DestinationLabel.Text = 'Destination';
            app.Q1SliderLabel = uilabel(app.UIFigure);
            app.Q1SliderLabel.Text = 'Q1';
            app.Q1Slider = uislider(app.UIFigure);
            app.Q1Slider.Limits = [-180 180];
            app.Q1Slider.MajorTicks = [-180 -90 0 90 180];
            app.Q1Slider.MajorTickLabels = {'-180', '-90', '0', '90', '180'};
            app.Q1Slider.ValueChangingFcn = createCallbackFcn(app, @Q1SliderValueChanging, true);
            app.Q1Slider.MinorTicks = [-180 -168 -156 -144 -132 -120 -108 -96 -84 -72 -60 -48 -36 -24 -12 0 12 24 36 48 60 72 84 96 108 120 132 144 156 168 180];
            app.Q2SliderLabel = uilabel(app.UIFigure);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q2Slider = uislider(app.UIFigure);
            app.Q2Slider.Limits = [-90 90];
            app.Q2Slider.MajorTicks = [-90 -45 0 45 90];
            app.Q2Slider.ValueChangingFcn = createCallbackFcn(app, @Q2SliderValueChanging, true);
            app.Q3SliderLabel = uilabel(app.UIFigure);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q3Slider = uislider(app.UIFigure);
            app.Q3Slider.Limits = [-160 160];
            app.Q3Slider.MajorTicks = [-160 -80 0 80 160];
            app.Q3Slider.ValueChangingFcn = createCallbackFcn(app, @Q3SliderValueChanging, true);
            app.Q4SliderLabel = uilabel(app.UIFigure);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q4Slider = uislider(app.UIFigure);
            app.Q4Slider.Limits = [-180 180];
            app.Q4Slider.MajorTicks = [-180 -90 0 90 180];
            app.Q4Slider.ValueChangingFcn = createCallbackFcn(app, @Q4SliderValueChanging, true);
            app.Q5SliderLabel = uilabel(app.UIFigure);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q5Slider = uislider(app.UIFigure);
            app.Q5Slider.Limits = [-180 180];
            app.Q5Slider.MajorTicks = [-180 -90 0 90 180];
            app.Q5Slider.ValueChangingFcn = createCallbackFcn(app, @Q5SliderValueChanging, true);
            app.Q6SliderLabel = uilabel(app.UIFigure);
            app.Q6SliderLabel.Text = 'Q6';
            app.Q6Slider = uislider(app.UIFigure);
            app.Q6Slider.Limits = [-360 360];
            app.Q6Slider.MajorTicks = [-360 -180 0 180 360];
            app.Q6Slider.ValueChangingFcn = createCallbackFcn(app, @Q6SliderValueChanging, true);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.RMRCButton = uibutton(app.UIFigure, 'push');
            app.RMRCButton.ButtonPushedFcn = createCallbackFcn(app, @RMRCButtonPushed, true);
            app.RMRCButton.Text = 'RMRC';
            app.ControlModeLabel = uilabel(app.UIFigure);
            app.ControlModeLabel.Text = {'Control '; 'Mode'};
            app.ControlModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ControlModeSwitch.Items = {'Normal', 'Joystick'};
            app.ControlModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlModeSwitchValueChanged, true);
            app.ControlModeSwitch.Value = 'Normal';
            app.EmergencyStopSwitchLabel = uilabel(app.UIFigure);
            app.EmergencyStopSwitchLabel.Text = 'Emergency Stop';
            app.EmergencyStopSwitch = uiswitch(app.UIFigure, 'slider');
            app.EmergencyStopSwitch.Items = {'Run', 'Stop'};
            app.EmergencyStopSwitch.Value = 'Run';
            app.FlipthePattiesButton = uibutton(app.UIFigure, 'push');
            app.FlipthePattiesButton.ButtonPushedFcn = createCallbackFcn(app, @FlipthePattiesButtonPushed, true);
            app.FlipthePattiesButton.Text = 'Flip the Patties';
            app.WorkerPositionSliderLabel = uilabel(app.UIFigure);
            app.WorkerPositionSliderLabel.Text = {'Worker'; 'Position'};
            app.WorkerPositionSlider = uislider(app.UIFigure);
            app.WorkerPositionSlider.Limits = [-0.1 0.1];
            app.WorkerPositionSlider.ValueChangingFcn = createCallbackFcn(app, @WorkerPositionSliderValueChanging, true);
            app.MoveWarningSymbolForwardButton = uibutton(app.UIFigure, 'push');
            app.MoveWarningSymbolForwardButton.ButtonPushedFcn = createCallbackFcn(app, @MoveWarningSymbolForwardButtonPushed, true);
            app.MoveWarningSymbolForwardButton.Text = 'Move Warning Symbol Forward';
            app.MoveWarningSymbolBackwardButton = uibutton(app.UIFigure, 'push');
            app.MoveWarningSymbolBackwardButton.ButtonPushedFcn = createCallbackFcn(app, @MoveWarningSymbolBackwardButtonPushed, true);
            app.MoveWarningSymbolBackwardButton.Text = 'Move Warning Symbol Backward';
            app.RotateWarningSymbolClockwiseButton = uibutton(app.UIFigure, 'push');
            app.RotateWarningSymbolClockwiseButton.ButtonPushedFcn = createCallbackFcn(app, @RotateWarningSymbolClockwiseButtonPushed, true);
            app.RotateWarningSymbolClockwiseButton.Text = 'Rotate Warning Symbol Clockwise';
            app.RotateWarningSymbolCounterClockwiseButton = uibutton(app.UIFigure, 'push');
            app.RotateWarningSymbolCounterClockwiseButton.ButtonPushedFcn = createCallbackFcn(app, @RotateWarningSymbolCounterClockwiseButtonPushed, true);
            app.RotateWarningSymbolCounterClockwiseButton.Text = 'Rotate Warning Symbol Counter-Clockwise';
            app.VisualServoingSwitchLabel = uilabel(app.UIFigure);
            app.VisualServoingSwitchLabel.Text = 'Visual Servoing';
            app.VisualServoingSwitch = uiswitch(app.UIFigure, 'slider');
            app.VisualServoingSwitch.ValueChangedFcn = createCallbackFcn(app, @VisualServoingSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CR5TeachFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end